classdef LSS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Toolbar                matlab.ui.container.Toolbar
        PushTool               matlab.ui.container.toolbar.PushTool
        PushTool3              matlab.ui.container.toolbar.PushTool
        PushTool2              matlab.ui.container.toolbar.PushTool
        GridLayout             matlab.ui.container.GridLayout
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        GridLayout_2           matlab.ui.container.GridLayout
        UITable                matlab.ui.control.Table
        epsilon                matlab.ui.control.DropDown
        Label_7                matlab.ui.control.Label
        Panel_2                matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        UIAxes5                matlab.ui.control.UIAxes
        Label_6                matlab.ui.control.Label
        Label_5                matlab.ui.control.Label
        Label_4                matlab.ui.control.Label
        sigma                  matlab.ui.control.Slider
        Label_3                matlab.ui.control.Label
        T                      matlab.ui.control.Slider
        Label_2                matlab.ui.control.Label
        D                      matlab.ui.control.Knob
        mmKnobLabel            matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes4                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        lamed                  matlab.ui.control.DropDown
        Label                  matlab.ui.control.Label
        delta_lamed            matlab.ui.control.Knob
        nmKnobLabel            matlab.ui.control.Label
        E                      matlab.ui.control.Knob
        uJKnobLabel            matlab.ui.control.Label
        Tab_2                  matlab.ui.container.Tab
        GridLayout4            matlab.ui.container.GridLayout
        FernaldLabel           matlab.ui.control.Label
        CollisLabel            matlab.ui.control.Label
        Image3                 matlab.ui.control.Image
        Image                  matlab.ui.control.Image
        ContextMenu            matlab.ui.container.ContextMenu
        Menu_7                 matlab.ui.container.Menu
        MatlabAppDesignerMenu  matlab.ui.container.Menu
        Menu_8                 matlab.ui.container.Menu
        Menu_9                 matlab.ui.container.Menu
        Menu_10                matlab.ui.container.Menu
        Menu_12                matlab.ui.container.Menu
        Menu_11                matlab.ui.container.Menu
        Menu_13                matlab.ui.container.Menu
    end

    properties (Access = private)
        E1
        D1
        delta_lamed1
        T1
        sigma1
        lamed1
        epsilon1
        sigma_Collis
        sigma_Fernald
        sigma_mean
        pb;%天空背景光亮度
        
        n1
        A
        nb
        snr
        Ns
        Ns1
        beta
        eta=0.38;%量子效率
            cps=500;%暗记数频率
            delta_t=0.1e-6; %采样间隔
            delta_r=15;  %距离分辨率，单位m
            h=6.626276e-34;%普朗克常数
            theta=1.2;%接收视场角，1.2mrad  
            c=3e8;%光速，单位m/s
        % Description
    end

    methods (Access = private)
        function plotwave(app)
              load Y.mat
              app.A=pi*app.D1^2/4;
              app.beta=app.sigma1^0.9;
              app.nb=app.eta*app.lamed1/(app.h*app.c)*app.delta_t*app.pb*pi*(app.theta/2)^2.*app.delta_lamed1*app.A*app.T1;%系统接收到的天空背景光
              nd=app.cps*app.delta_t;%探测器暗记数
              for i=1:250
                  r(i)=i*0.015;
                  P(i)=app.E1*app.c*app.A*exp(-2*app.sigma1*r(i))*app.T1.*Y(i)*app.beta/(2*r(i).^2);%接收功率
                  P1(i)=app.E1*app.c*app.A*exp(-2*app.sigma1*r(i))*app.T1.*app.beta/(2*r(i).^2);%无几何因子的接收功率
                  app.Ns(i)=app.eta*app.lamed1/(app.h*app.c).*P(i)*app.delta_t;%激光雷达接收到的大气后向散射回波信号的光电子数
                  app.Ns1(i)=app.eta*app.lamed1/(app.h*app.c).*P1(i)*app.delta_t;%无几何因子的回波信号
                  m=1;
                  app.snr(i)=app.Ns(i)/sqrt(app.Ns(i)+2*(app.nb+nd))*sqrt(m);%信噪比
                  S(i)=log(app.Ns(i).*r(i).^2);
                  S1(i)=log(app.Ns1(i).*r(i).^2);%无几何因子的距离平方修正信号 
              end
              %% Collis斜率法
              fit=polyfit(r(1,40:250),S(1,40:250),1);%一阶线性拟合
              for i=1:250
                  app.sigma_Collis(i)=abs(fit(1)/2);
              end
%% Fernald 法
Sa=50;
Sm=8*pi/3;
sigma_0=0.995*app.sigma1;
sigma_m=1.3e-2;

for i=1:250
    r(i)=0.015.*i;
    X(i)=exp(S1(i));
    Part1(i)=X(i).*exp((Sa-Sm)*(2*sigma_m/(8*pi/3))*0.015);
    Part2(i)=X(i)./(sigma_0+Sa/Sm*sigma_m);
    Part4(i)=-Sa/Sm*sigma_m;
end
for i=2:250
    Part3(i)=(X(i)+Part1(i-1))*0.015;%分母右项
end
for i=250:-1:2
    app.sigma_Fernald(i-1)=Part4(i)+ Part1(i-1)./(Part2(i)+Part3(i));%计算探测路径上各点消光系数sigma(i-1),sigma(i-2),....sigma(1) 
end
app.sigma_mean=(sum(sum(app.sigma_Fernald))+sigma_0)/250;%平均值

app.n1=0;

while abs(app.sigma_mean-sigma_0)/app.sigma_mean>0.001
sigma_0=app.sigma_mean;
for i=1:250
    r(i)=0.015.*i;
    X(i)=exp(S1(i));
    Part1(i)=X(i).*exp((Sa-Sm)*(2*sigma_m/(8*pi/3))*0.015);
    Part2(i)=X(i)./(sigma_0+Sa/Sm*sigma_m);
    Part4(i)=-Sa/Sm*sigma_m;
end
for i=2:250
    Part3(i)=(X(i)+Part1(i-1))*0.015;%分母右项
end
for i=250:-1:2
    app.sigma_Fernald(i-1)=Part4(i)+ Part1(i-1)./(Part2(i)+Part3(i));%计算探测路径上各点消光系数sigma(i-1),sigma(i-2),....sigma(1) 
end
app.sigma_mean=(sum(sum(app.sigma_Fernald))+sigma_0)/250;%平均值
app.n1=app.n1+1;%统计迭代次数
end

for i=1:250
    app.sigma_Fernald(i)=app.sigma_mean;%把均值重新赋值给sigma_Fernald,以便于作图
end

for i=1:250
        V_Collis(i)=app.epsilon1/app.sigma_Collis(i);
        V_Fernald(i)=app.epsilon1/app.sigma_Fernald(i);
end


 plot(app.UIAxes,r,app.Ns,"Color",'c',"LineStyle","-","LineWidth",1);
 semilogy(app.UIAxes2,r,app.snr,"Color",'m',"LineStyle","-","LineWidth",1);

 plot(app.UIAxes3,r,app.sigma_Collis,"Color",'b',"LineWidth",1,'LineStyle','-');
 hold(app.UIAxes3,"on");
 plot(app.UIAxes3,r,app.sigma_Fernald,"Color",'y',"LineWidth",1,'LineStyle','--');
 hold(app.UIAxes3,"off");
 legend(app.UIAxes3,{'Collis','Fernald'},'Location','southeast');

 plot(app.UIAxes4,r,V_Collis,"Color",'b',"LineWidth",1,'LineStyle','-');
 hold(app.UIAxes4,"on");
 plot(app.UIAxes4,r,V_Fernald,"Color",'y',"LineWidth",1,'LineStyle','--');
 hold(app.UIAxes4,"off");
 legend(app.UIAxes4,{'Collis','Fernald'},'Location','southeast');

 plot(app.UIAxes5,r(1,1:66),Y(1,1:66));
 end
%% 通用设置
        function CommonSet(app)
        app.E1=app.E.Value*(1e-6);
        app.D1=app.D.Value*(1e-3);
        app.delta_lamed1=app.delta_lamed.Value;
        app.T1=app.T.Value;
        app.sigma1=app.sigma1;
        app.lamed1=app.lamed1;
        app.epsilon1=app.epsilon1;

        plotwave(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.E1=10*(1e-6);
        app.D1=100*(1e-3);
        app.delta_lamed1=0.1;
        app.T1=0.04;
        app.sigma1=0.2;
        app.lamed1=532;
        app.epsilon1=3.912;
        app.pb=0.2;
        plotwave(app);

        method{1,1}='Collis'; method{1,2}='Fernald';  
            error1=abs(app.sigma1-app.sigma_Collis(1))./app.sigma1;
            error2=abs(app.sigma1-app.sigma_Fernald(1))./app.sigma1;
            n{1,1}='——'; n{1,2}=app.n1; 

            app.UITable.Data={method{1,1},num2str(app.sigma1),num2str(app.sigma_Collis(1)),[num2str(sprintf('%8.4f',100*error1)),'%'],n{1,1};...
                method{1,2},num2str(app.sigma1),num2str(app.sigma_Fernald(1)),[num2str(sprintf('%8.4f',100*error2)),'%'],num2str(int8(n{1,2}))};


        end

        % Value changed function: lamed
        function lamedValueChanged(app, event)
           
            value = app.lamed.Value;
            if value=="532nm"
                app.lamed1=532;
                app.pb=0.2;
            elseif value=="905nm"
                app.lamed1=905;
                app.pb=0.9;
            else
                app.lamed1=1064;
                app.pb=0.08;
            end
            CommonSet(app) 

            method{1,1}='Collis'; method{1,2}='Fernald';  
            error1=abs(app.sigma1-app.sigma_Collis(1))./app.sigma1;
            error2=abs(app.sigma1-app.sigma_Fernald(1))./app.sigma1;
            n{1,1}='——'; n{1,2}=app.n1; 

            app.UITable.Data={method{1,1},num2str(app.sigma1),num2str(app.sigma_Collis(1)),[num2str(sprintf('%8.4f',100*error1)),'%'],n{1,1};...
                method{1,2},num2str(app.sigma1),num2str(app.sigma_Fernald(1)),[num2str(sprintf('%8.4f',100*error2)),'%'],num2str(int8(n{1,2}))};
        end

        % Value changed function: E
        function EValueChanged(app, event)
      
            value = app.E.Value;
             app.E1=value;
            CommonSet(app)

            method{1,1}='Collis'; method{1,2}='Fernald';  
            error1=abs(app.sigma1-app.sigma_Collis(1))./app.sigma1;
            error2=abs(app.sigma1-app.sigma_Fernald(1))./app.sigma1;
            n{1,1}='——'; n{1,2}=app.n1; 

            app.UITable.Data={method{1,1},num2str(app.sigma1),num2str(app.sigma_Collis(1)),[num2str(sprintf('%8.4f',100*error1)),'%'],n{1,1};...
                method{1,2},num2str(app.sigma1),num2str(app.sigma_Fernald(1)),[num2str(sprintf('%8.4f',100*error2)),'%'],num2str(int8(n{1,2}))};
        end

        % Value changed function: D
        function DValueChanged(app, event)
           
            value = app.D.Value;
            app.D1=value;
            CommonSet(app)
            method{1,1}='Collis'; method{1,2}='Fernald';  
            error1=abs(app.sigma1-app.sigma_Collis(1))./app.sigma1;
            error2=abs(app.sigma1-app.sigma_Fernald(1))./app.sigma1;
            n{1,1}='——'; n{1,2}=app.n1; 

            app.UITable.Data={method{1,1},num2str(app.sigma1),num2str(app.sigma_Collis(1)),[num2str(sprintf('%8.4f',100*error1)),'%'],n{1,1};...
                method{1,2},num2str(app.sigma1),num2str(app.sigma_Fernald(1)),[num2str(sprintf('%8.4f',100*error2)),'%'],num2str(int8(n{1,2}))};
        end

        % Value changed function: delta_lamed
        function delta_lamedValueChanged(app, event)
            value = app.delta_lamed.Value;
            app.delta_lamed1=value;
          
CommonSet(app)
method{1,1}='Collis'; method{1,2}='Fernald';  
            error1=abs(app.sigma1-app.sigma_Collis(1))./app.sigma1;
            error2=abs(app.sigma1-app.sigma_Fernald(1))./app.sigma1;
            n{1,1}='——'; n{1,2}=app.n1; 

            app.UITable.Data={method{1,1},num2str(app.sigma1),num2str(app.sigma_Collis(1)),[num2str(sprintf('%8.4f',100*error1)),'%'],n{1,1};...
                method{1,2},num2str(app.sigma1),num2str(app.sigma_Fernald(1)),[num2str(sprintf('%8.4f',100*error2)),'%'],num2str(int8(n{1,2}))};
        end

        % Value changed function: T
        function TValueChanged(app, event)
          
            value = app.T.Value;
            app.T1=value;
            CommonSet(app)

            method{1,1}='Collis'; method{1,2}='Fernald';  
            error1=abs(app.sigma1-app.sigma_Collis(1))./app.sigma1;
            error2=abs(app.sigma1-app.sigma_Fernald(1))./app.sigma1;
            n{1,1}='——'; n{1,2}=app.n1; 

            app.UITable.Data={method{1,1},num2str(app.sigma1),num2str(app.sigma_Collis(1)),[num2str(sprintf('%8.4f',100*error1)),'%'],n{1,1};...
                method{1,2},num2str(app.sigma1),num2str(app.sigma_Fernald(1)),[num2str(sprintf('%8.4f',100*error2)),'%'],num2str(int8(n{1,2}))};
        end

        % Value changed function: epsilon
        function epsilonValueChanged(app, event)
            value = app.epsilon.Value;
            if value=="0.02"
                app.epsilon1=3.912;
            else
                app.epsilon1=2.996;
            end
            CommonSet(app) 

            method{1,1}='Collis'; method{1,2}='Fernald';  
            error1=abs(app.sigma1-app.sigma_Collis(1))./app.sigma1;
            error2=abs(app.sigma1-app.sigma_Fernald(1))./app.sigma1;
            n{1,1}='——'; n{1,2}=app.n1; 

            app.UITable.Data={method{1,1},num2str(app.sigma1),num2str(app.sigma_Collis(1)),[num2str(sprintf('%8.4f',100*error1)),'%'],n{1,1};...
                method{1,2},num2str(app.sigma1),num2str(app.sigma_Fernald(1)),[num2str(sprintf('%8.4f',100*error2)),'%'],num2str(int8(n{1,2}))};
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            delete(app);
        end

        % Menu selected function: Menu_9
        function Menu_9Selected(app, event)
            delete(app);
        end

        % Menu selected function: Menu_10
        function Menu_10Selected(app, event)
            app.TabGroup.SelectedTab=app.Tab;
        end

        % Menu selected function: Menu_12
        function Menu_12Selected(app, event)
            app.TabGroup.SelectedTab=app.Tab_2;
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            winopen help.pdf;
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
            winopen readme.pdf;
        end

        % Menu selected function: MatlabAppDesignerMenu
        function MatlabAppDesignerMenuSelected(app, event)
            url='https://www.bilibili.com/video/BV16f4y147x9?spm_id_from=333.999.0.0';
            web(url,'-browser');
        end

        % Menu selected function: Menu_11
        function Menu_11Selected(app, event)
            url='https://https://github.com/Taozheng258/AppDesigner';
            web(url,'-browser');
        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)
           app.E.Value=10;
            app.E1=10*(1e-6);
            app.D.Value=100;
        app.D1=100*(1e-3);
        app.delta_lamed.Value=0.1;
        app.delta_lamed1=0.1;
        app.T.Value=0.04;
        app.T1=0.04;
        app.sigma.Value=0.2;
        app.sigma1=0.2;
        app.lamed.Value='532nm';
        app.lamed1=532;
        app.epsilon.Value='0.02';
        app.epsilon1=3.912;

        plotwave(app);

        method{1,1}='Collis'; method{1,2}='Fernald';  
            error1=abs(app.sigma1-app.sigma_Collis(1))./app.sigma1;
            error2=abs(app.sigma1-app.sigma_Fernald(1))./app.sigma1;
            n{1,1}='——'; n{1,2}=app.n1; 

            app.UITable.Data={};
            app.TabGroup.SelectedTab=app.Tab;
        end

        % Menu selected function: Menu_13
        function Menu_13Selected(app, event)
           
            app.E.Value=10;
            app.E1=10*(1e-6);
            app.D.Value=100;
        app.D1=100*(1e-3);
        app.delta_lamed.Value=0.1;
        app.delta_lamed1=0.1;
        app.T.Value=0.04;
        app.T1=0.04;
        app.sigma.Value=0.2;
        app.sigma1=0.2;
        app.lamed.Value='532nm';
        app.lamed1=532;
        app.epsilon.Value='0.02';
        app.epsilon1=3.912;

        plotwave(app);

        method{1,1}='Collis'; method{1,2}='Fernald';  
            error1=abs(app.sigma1-app.sigma_Collis(1))./app.sigma1;
            error2=abs(app.sigma1-app.sigma_Fernald(1))./app.sigma1;
            n{1,1}='——'; n{1,2}=app.n1; 

            app.UITable.Data={};
            app.TabGroup.SelectedTab=app.Tab;
        end

        % Value changing function: sigma
        function sigmaValueChanging(app, event)
                        changingValue = event.Value;
                       app.sigma1=changingValue ;
            
            CommonSet(app);

            method{1,1}='Collis'; method{1,2}='Fernald';  
            error1=abs(app.sigma1-app.sigma_Collis(1))./app.sigma1;
            error2=abs(app.sigma1-app.sigma_Fernald(1))./app.sigma1;
            n{1,1}='——'; n{1,2}=app.n1; 

            app.UITable.Data={method{1,1},num2str(app.sigma1),num2str(app.sigma_Collis(1)),[num2str(sprintf('%8.4f',100*error1)),'%'],n{1,1};...
                method{1,2},num2str(app.sigma1),num2str(app.sigma_Fernald(1)),[num2str(sprintf('%8.4f',100*error2)),'%'],num2str(int8(n{1,2}))};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'帮助'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'help.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'重置'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = 'reset.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'退出'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'exit.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '功能面板';
            app.GridLayout_2 = uigridlayout(app.Tab);
            app.uJKnobLabel = uilabel(app.GridLayout_2);
            app.uJKnobLabel.Text = {'发射能量(uJ)'; ''};
            app.E = uiknob(app.GridLayout_2, 'continuous');
            app.E.Limits = [10 100];
            app.E.ValueChangedFcn = createCallbackFcn(app, @EValueChanged, true);
            app.E.Tooltip = {'激光雷达的发射能量'};
            app.E.Value = 10;
            app.nmKnobLabel = uilabel(app.GridLayout_2);
            app.nmKnobLabel.Text = '滤光片半宽度(nm)';
            app.delta_lamed = uiknob(app.GridLayout_2, 'continuous');
            app.delta_lamed.Limits = [0.1 10];
            app.delta_lamed.ValueChangedFcn = createCallbackFcn(app, @delta_lamedValueChanged, true);
            app.delta_lamed.Tooltip = {'滤光片半宽度，可以抑制天空背景光，提高信噪比，同等条件下，厚度越小，效果越好'};
            app.delta_lamed.Value = 0.1;
            app.Label = uilabel(app.GridLayout_2);
            app.Label.Text = '波长：';
            app.lamed = uidropdown(app.GridLayout_2);
            app.lamed.Items = {'532nm', '905nm', '1064nm'};
            app.lamed.ValueChangedFcn = createCallbackFcn(app, @lamedValueChanged, true);
            app.lamed.Tooltip = {'发射激光的波长'};
            app.lamed.Value = '532nm';
            app.Panel = uipanel(app.GridLayout_2);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'SNR')
            xlabel(app.UIAxes2, 'r/Km')
            ylabel(app.UIAxes2, 'SNR(db)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, '能见度')
            xlabel(app.UIAxes4, 'r/Km')
            ylabel(app.UIAxes4, 'V(Km)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, '回波信号')
            xlabel(app.UIAxes, 'r/Km')
            ylabel(app.UIAxes, 'PhE/S')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, '消光系数反演值')
            xlabel(app.UIAxes3, 'r/Km')
            ylabel(app.UIAxes3, 'sigma(Km^-1)')
            zlabel(app.UIAxes3, 'Z')
            app.mmKnobLabel = uilabel(app.GridLayout_2);
            app.mmKnobLabel.Text = '接收器直径(mm)';
            app.D = uiknob(app.GridLayout_2, 'continuous');
            app.D.Limits = [100 300];
            app.D.ValueChangedFcn = createCallbackFcn(app, @DValueChanged, true);
            app.D.Tooltip = {'激光雷达接收望远镜的直径。同等条件下，直径越大，接收到的光电子数就越多'};
            app.D.Value = 100;
            app.Label_2 = uilabel(app.GridLayout_2);
            app.Label_2.Text = '透过率';
            app.T = uislider(app.GridLayout_2);
            app.T.Limits = [0 1];
            app.T.ValueChangedFcn = createCallbackFcn(app, @TValueChanged, true);
            app.T.Tooltip = {'系统发射与接收的总透过率'};
            app.T.Value = 0.04;
            app.Label_3 = uilabel(app.GridLayout_2);
            app.Label_3.Text = '消光系数';
            app.sigma = uislider(app.GridLayout_2);
            app.sigma.Limits = [0 5];
            app.sigma.ValueChangingFcn = createCallbackFcn(app, @sigmaValueChanging, true);
            app.sigma.Tooltip = {'消光系数预设值'};
            app.sigma.Value = 0.2;
            app.Label_4 = uilabel(app.GridLayout_2);
            app.Label_4.Text = '晴';
            app.Label_5 = uilabel(app.GridLayout_2);
            app.Label_5.Text = '阴';
            app.Label_6 = uilabel(app.GridLayout_2);
            app.Label_6.Text = '雾';
            app.Panel_2 = uipanel(app.GridLayout_2);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.UIAxes5 = uiaxes(app.GridLayout3);
            title(app.UIAxes5, '几何因子')
            xlabel(app.UIAxes5, 'r/Km')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Label_7 = uilabel(app.GridLayout_2);
            app.Label_7.Text = '阈值：';
            app.epsilon = uidropdown(app.GridLayout_2);
            app.epsilon.Items = {'0.02', '0.05'};
            app.epsilon.ValueChangedFcn = createCallbackFcn(app, @epsilonValueChanged, true);
            app.epsilon.Tooltip = {'人眼的视觉阈值，一般取为0.02，对于航空气象部门，为保证飞行安全，取值为0.05'};
            app.epsilon.Value = '0.02';
            app.UITable = uitable(app.GridLayout_2);
            app.UITable.ColumnName = {'方法'; '预设真值'; '反演结果'; '误差'; '迭代次数'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '算法说明';
            app.GridLayout4 = uigridlayout(app.Tab_2);
            app.Image = uiimage(app.GridLayout4);
            app.Image.ImageSource = 'Collis.png';
            app.Image3 = uiimage(app.GridLayout4);
            app.Image3.ImageSource = 'Fernald.png';
            app.CollisLabel = uilabel(app.GridLayout4);
            app.CollisLabel.Text = 'Collis斜率法';
            app.FernaldLabel = uilabel(app.GridLayout4);
            app.FernaldLabel.Text = 'Fernald迭代反演算法';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu_7 = uimenu(app.ContextMenu);
            app.Menu_7.Text = '帮助       ';
            app.MatlabAppDesignerMenu = uimenu(app.Menu_7);
            app.MatlabAppDesignerMenu.MenuSelectedFcn = createCallbackFcn(app, @MatlabAppDesignerMenuSelected, true);
            app.MatlabAppDesignerMenu.Accelerator = 'h';
            app.MatlabAppDesignerMenu.Text = '了解Matlab AppDesigner';
            app.Menu_8 = uimenu(app.Menu_7);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Accelerator = 'a';
            app.Menu_8.Text = '关于程序';
            app.Menu_9 = uimenu(app.ContextMenu);
            app.Menu_9.MenuSelectedFcn = createCallbackFcn(app, @Menu_9Selected, true);
            app.Menu_9.Text = '退出';
            app.Menu_10 = uimenu(app.ContextMenu);
            app.Menu_10.MenuSelectedFcn = createCallbackFcn(app, @Menu_10Selected, true);
            app.Menu_10.Text = '功能面板';
            app.Menu_12 = uimenu(app.ContextMenu);
            app.Menu_12.MenuSelectedFcn = createCallbackFcn(app, @Menu_12Selected, true);
            app.Menu_12.Text = '算法说明';
            app.Menu_11 = uimenu(app.ContextMenu);
            app.Menu_11.MenuSelectedFcn = createCallbackFcn(app, @Menu_11Selected, true);
            app.Menu_11.Accelerator = 's';
            app.Menu_11.Text = '源码获取';
            app.Menu_13 = uimenu(app.ContextMenu);
            app.Menu_13.MenuSelectedFcn = createCallbackFcn(app, @Menu_13Selected, true);
            app.Menu_13.Accelerator = 'r';
            app.Menu_13.Text = '重置';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            app.GridLayout.ContextMenu = app.ContextMenu;
            app.TabGroup.ContextMenu = app.ContextMenu;
            app.Tab.ContextMenu = app.ContextMenu;
            app.GridLayout_2.ContextMenu = app.ContextMenu;
            app.E.ContextMenu = app.ContextMenu;
            app.delta_lamed.ContextMenu = app.ContextMenu;
            app.lamed.ContextMenu = app.ContextMenu;
            app.Panel.ContextMenu = app.ContextMenu;
            app.GridLayout2.ContextMenu = app.ContextMenu;
            app.UIAxes2.ContextMenu = app.ContextMenu;
            app.UIAxes4.ContextMenu = app.ContextMenu;
            app.UIAxes.ContextMenu = app.ContextMenu;
            app.D.ContextMenu = app.ContextMenu;
            app.T.ContextMenu = app.ContextMenu;
            app.sigma.ContextMenu = app.ContextMenu;
            app.Label_4.ContextMenu = app.ContextMenu;
            app.Label_5.ContextMenu = app.ContextMenu;
            app.Label_6.ContextMenu = app.ContextMenu;
            app.Panel_2.ContextMenu = app.ContextMenu;
            app.GridLayout3.ContextMenu = app.ContextMenu;
            app.UIAxes5.ContextMenu = app.ContextMenu;
            app.epsilon.ContextMenu = app.ContextMenu;
            app.UITable.ContextMenu = app.ContextMenu;
            app.Tab_2.ContextMenu = app.ContextMenu;
            app.GridLayout4.ContextMenu = app.ContextMenu;
            app.Image.ContextMenu = app.ContextMenu;
            app.Image3.ContextMenu = app.ContextMenu;
            app.CollisLabel.ContextMenu = app.ContextMenu;
            app.FernaldLabel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LSS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end